classdef ImgResize_Hengshan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        selectDist  matlab.ui.control.Button
        dirPath     matlab.ui.control.Label
    end

    
    properties (Access = private)
        subDir = '';
        No = 1;
        n = 0;
    end
    
    methods (Access = private)
        
        function reSize(app,subDir)
                
            subDirpath = fullfile(subDir(app.No).folder, subDir(app.No).name, '\');
            newpath = fullfile(subDir(app.No).folder, '压缩后', '\', subDir(app.No).name, '\');
            mkdir(newpath);
            
            imgDir = dir([subDirpath '*.jpg']);
            
            % 动态压缩图片
            figureWait = waitbar(0,'压缩图像中...');
            for j = 1:length(imgDir) % 遍历结构体就可以一一处理图片了
                waitbar(j/length(imgDir), figureWait, '压缩图像中...');
                
                % 压缩图片
                OrigImg = imread(fullfile(imgDir(j).folder,imgDir(j).name));
                ImgSize = size(OrigImg);
                ImgRatio = ImgSize(1)/ImgSize(2);
                
                if ImgSize(1) > 3600
                    height = round(3600/ImgRatio);
                    NewImg = imresize(OrigImg,[3600,height]);
                    ImgSize = size(NewImg);
                end
                if ImgSize(2) > 2700
                    width = round(2700*ImgRatio);
                    NewImg = imresize(OrigImg,[width,2700]);
                end
                imwrite(NewImg,fullfile(newpath,imgDir(j).name));
            end
            close(figureWait);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: selectDist
        function selectDistButtonPushed(app, event)
            mainDir = uigetdir('D:\农房工程文件夹','选择文件夹'); % 资料整理_>村级编码
            app.subDir = dir(mainDir); %确定子文件夹
            app.subDir(1) = [];app.subDir(1) = []; %删除隐藏文件夹
            app.n = length(app.subDir); %户数
            app.dirPath.Text = mainDir;
            for i=1:app.n
                app.reSize(app.subDir);
                app.No = app.No + 1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.selectDist = uibutton(app.UIFigure, 'push');
            app.selectDist.ButtonPushedFcn = createCallbackFcn(app, @selectDistButtonPushed, true);
            app.selectDist.Text = '选择文件夹';
            app.dirPath = uilabel(app.UIFigure);
            app.dirPath.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImgResize_Hengshan

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end